classdef gui_codi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        COMPARACIDELERRORDEMESURALabel  matlab.ui.control.Label
        MireiaAndreuPauLabel            matlab.ui.control.Label
        MireiaAndreuPauLabel_2          matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        FiltreMultimetresListBoxLabel   matlab.ui.control.Label
        FiltreMultimetresListBox        matlab.ui.control.ListBox
        MagnitudDropDownLabel           matlab.ui.control.Label
        MagnitudDropDown                matlab.ui.control.DropDown
        PercompararmultiplesmultimetresseleccionilsfentCTRLclicLabel  matlab.ui.control.Label
        TotsButton                      matlab.ui.control.Button
        RestablirButton                 matlab.ui.control.Button
        GrficLabel                      matlab.ui.control.Label
        EixLecturaLabel                 matlab.ui.control.Label
        xLogSwitch                      matlab.ui.control.Switch
        xAbsSwitch                      matlab.ui.control.Switch
        EixErrorLabel                   matlab.ui.control.Label
        yLogSwitch                      matlab.ui.control.Switch
        yAbsSwitch                      matlab.ui.control.Switch
        EscalaDropDownLabel             matlab.ui.control.Label
        EscalaDropDown                  matlab.ui.control.DropDown
    end

    
    properties (Access = public)
      
        dmms multimetre
        dmms_disp multimetre
        dmms_sel multimetre
        selectAllWhenChange = false;
        xlog = true;
        ylog = true;
        modeAbs = true;
        
        mesures mesura
        M = containers.Map('KeyType', 'char');
    end
    
    methods (Access = private)
        
        function actualitzarMenus(app)
            app.dmms_sel = app.FiltreMultimetresListBox.Value;
            actualitzarGrafic(app);             
        end


        function actualitzarGrafic(app)
            a = {};
            color_sel = {};
            a_i = 1;
            colors = [0 0 1;1 0 0;0.9294 0.6941 0.1255;0.4941 0.1843 0.5569;0.4667 0.6745 0.1882;0.302 0.7451 0.9333;0.6353 0.0784 0.1843];
            cla(app.UIAxes)
            for dmm_i = app.dmms_sel
                [xi,yi]=generarPunts(dmm_i.M(app.MagnitudDropDown.Value), [], app.modeAbs);
                if app.xlog == true
                    xi = log10(xi);
                end
                if app.ylog == true
                    yi = log10(yi);
                end
                a(a_i) = {[xi,yi]};
                
                plot(app.UIAxes, a{a_i}(:,1), a{a_i}(:,2), "Color", colors(dmm_i.id,:));
                a_i = a_i + 1;
                %plot(app.UIAxes, xi, yi);
            end
            
            actualitzarEixos(app);


            legend(app.UIAxes,{app.dmms_sel.model},'Location',"northwest")
            
            
            title(app.UIAxes, app.MagnitudDropDown.Value);
    
        end


        function actualitzarEixos(app)
            if app.modeAbs
                app.UIAxes.YLabel.String='|Error Absolut|';
                xlabel(app.UIAxes, '|L/FE|')
                unitat = app.dmms_sel(1).M(app.MagnitudDropDown.Value).unitat;
                
            else
                app.UIAxes.YLabel.String='|Error/FE|';
                xlabel(app.UIAxes, 'Lectura')
                unitat = ' ';
            end
            
            if ~isempty(app.dmms_sel)
                
                
                xvals_loc = [];
                yvals_loc = [];
                switch unitat
                    case "°F"
                        yvals = 10.^[-9:1:3];
                        xvals = [-100, -50, 0, 100, 1000]*1.8+32;
                        xvals_loc = (xvals+ 459.67)*5/9;
                    case "°C"
                        yvals = 10.^[-9:1:3];
                        xvals = [-100, -50, 0, 100, 1000];
                        xvals_loc = xvals+273;
                    case "%"
                        yvals = 10.^[-9:1:3];
                        xvals = [10, 50, 100];
                        xvals_loc = xvals;
                    otherwise
                        yvals = 10.^[-9:1:3];
                        xvals = 4*10.^[-6:4];
                        xvals_loc = xvals;     
                end
               %EIX X
                xlabels = {};
                if app.xlog == true
                    for xval_i = xvals
                        xlabels = [xlabels, genPrefix(xval_i,unitat)];
                    end
                    xvals_loc = log10(xvals_loc);
                    xticks(app.UIAxes, xvals_loc);
                else
                    xticks(app.UIAxes,'auto');
                    ticksAutoX = xticks(app.UIAxes);
                    for xval_i = ticksAutoX   
                        xlabels = [xlabels, genPrefix(xval_i, unitat)];
                    end
                    
                end
                xticklabels(app.UIAxes, xlabels);

                %Eix Y
                if app.modeAbs
                    ylabels = {};
                    for yval_i = yvals
                        ylabels = [ylabels, genPrefix(yval_i,unitat)];
                    end
                    if app.ylog == true
                        yvals_loc = log10(yvals);
                        yticks(app.UIAxes, yvals_loc);
                        yticklabels(app.UIAxes, ylabels);
                    end
                else
                    yticks(app.UIAxes,'auto');
                    yticklabels(app.UIAxes, 'auto');
                    xticks(app.UIAxes,'auto');
                    xticklabels(app.UIAxes, 'auto');
                end


                axis (app.UIAxes, "auto");
                %yticklabels(10.^[-5:1:3]);
                
                
            end
            
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tmp = load("Taula_Dades.mat");%evalin('base', 'dvm');
            app.dmms = tmp.('dvm');
            
            app.MagnitudDropDown.Items = mesuresDict(app.dmms).keys;
            app.MagnitudDropDown.Value = 'Voltatge DC';
            
            app.dmms_disp = buscarDmms(app.dmms, app.MagnitudDropDown.Value);
            
            app.FiltreMultimetresListBox.Items = [app.dmms_disp.model];
            app.FiltreMultimetresListBox.ItemsData = [app.dmms_disp];
            app.FiltreMultimetresListBox.Value = [app.dmms_disp(1)];
            app.xLogSwitch.ItemsData = {true, false};
            app.xAbsSwitch.ItemsData = {true, false};
            app.yLogSwitch.ItemsData = {true, false};
            app.yAbsSwitch.ItemsData = {true, false};
            
            %app.dmms_sel = app.FiltreMultimetresListBox.Value;
            
            actualitzarMenus(app);
        end

        % Callback function
        function menuActualitzat(app, event)
            actualitzarMenus(app);
            
        end

        % Value changed function: MagnitudDropDown
        function MagnitudDropDownValueChanged(app, event)
            app.dmms_disp = buscarDmms(app.dmms, app.MagnitudDropDown.Value);
            app.FiltreMultimetresListBox.Items = [app.dmms_disp.model];
            app.FiltreMultimetresListBox.ItemsData = [app.dmms_disp];
            if app.selectAllWhenChange 
                app.FiltreMultimetresListBox.Value = [app.dmms_disp];
            end
            actualitzarMenus(app);
        end

        % Value changed function: FiltreMultimetresListBox
        function FiltreMultimetresListBoxValueChanged(app, event)
            app.selectAllWhenChange = false;
            actualitzarMenus(app);
        end

        % Button pushed function: TotsButton
        function TotsButtonPushed(app, event)
            app.FiltreMultimetresListBox.Value = [app.dmms_disp];
            app.selectAllWhenChange = true;
            actualitzarMenus(app);
        end

        % Button pushed function: RestablirButton
        function RestablirButtonPushed(app, event)
            %cla(app.UIAxes,'reset')
            %app.UIAxes.NextPlot = 'add'
            %app.UIAxes.SortMethod = 'depth';
            %actualitzarMenus(app);
            actualitzarEixos(app);
        end

        % Value changed function: xAbsSwitch, xLogSwitch, 
        % yAbsSwitch, yLogSwitch
        function switchCanviat(app, event)
            app.xlog = app.xLogSwitch.Value;
            app.modeAbs = app.xAbsSwitch.Value;
            app.ylog = app.yLogSwitch.Value;
            actualitzarMenus(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sense dades')
            xlabel(app.UIAxes, '|L/FE|')
            ylabel(app.UIAxes, '|Error/FE|')
            app.COMPARACIDELERRORDEMESURALabel = uilabel(app.UIFigure);
            app.COMPARACIDELERRORDEMESURALabel.Text = 'COMPARACIÓ DEL ERROR DE MESURA';
            app.MireiaAndreuPauLabel = uilabel(app.UIFigure);
            app.MireiaAndreuPauLabel.Text = {'Mireia Andreu, Pau Besses, Paula Campiña, Eva Maria Deltor, Adria Freixa, Pau Jordan Oliveras, Joan Peña'; ''};
            app.MireiaAndreuPauLabel_2 = uilabel(app.UIFigure);
            app.MireiaAndreuPauLabel_2.Text = {'GIE02'; ''};
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '26/05/2020';
            app.FiltreMultimetresListBoxLabel = uilabel(app.UIFigure);
            app.FiltreMultimetresListBoxLabel.Text = 'Filtre Multimetres';
            app.FiltreMultimetresListBox = uilistbox(app.UIFigure);
            app.FiltreMultimetresListBox.Multiselect = 'on';
            app.FiltreMultimetresListBox.ValueChangedFcn = createCallbackFcn(app, @FiltreMultimetresListBoxValueChanged, true);
            app.FiltreMultimetresListBox.Value = {'Item 1'};
            app.MagnitudDropDownLabel = uilabel(app.UIFigure);
            app.MagnitudDropDownLabel.Text = 'Magnitud';
            app.MagnitudDropDown = uidropdown(app.UIFigure);
            app.MagnitudDropDown.ValueChangedFcn = createCallbackFcn(app, @MagnitudDropDownValueChanged, true);
            app.PercompararmultiplesmultimetresseleccionilsfentCTRLclicLabel = uilabel(app.UIFigure);
            app.PercompararmultiplesmultimetresseleccionilsfentCTRLclicLabel.Text = {'Per comparar multiples multimetres,'; 'seleccioni''ls fent ''CTRL'' + clic.'};
            app.TotsButton = uibutton(app.UIFigure, 'push');
            app.TotsButton.ButtonPushedFcn = createCallbackFcn(app, @TotsButtonPushed, true);
            app.TotsButton.Text = 'Tots';
            app.RestablirButton = uibutton(app.UIFigure, 'push');
            app.RestablirButton.ButtonPushedFcn = createCallbackFcn(app, @RestablirButtonPushed, true);
            app.RestablirButton.Text = 'Restablir';
            app.GrficLabel = uilabel(app.UIFigure);
            app.GrficLabel.Text = 'Gràfic';
            app.EixLecturaLabel = uilabel(app.UIFigure);
            app.EixLecturaLabel.Text = 'Eix Lectura';
            app.xLogSwitch = uiswitch(app.UIFigure, 'slider');
            app.xLogSwitch.Items = {'Log', 'Lin'};
            app.xLogSwitch.ValueChangedFcn = createCallbackFcn(app, @switchCanviat, true);
            app.xLogSwitch.Value = 'Log';
            app.xAbsSwitch = uiswitch(app.UIFigure, 'slider');
            app.xAbsSwitch.Items = {'Absolut', 'Relatiu'};
            app.xAbsSwitch.ValueChangedFcn = createCallbackFcn(app, @switchCanviat, true);
            app.xAbsSwitch.Value = 'Absolut';
            app.EixErrorLabel = uilabel(app.UIFigure);
            app.EixErrorLabel.Text = 'Eix Error';
            app.yLogSwitch = uiswitch(app.UIFigure, 'slider');
            app.yLogSwitch.Items = {'Log', 'Lin'};
            app.yLogSwitch.ValueChangedFcn = createCallbackFcn(app, @switchCanviat, true);
            app.yLogSwitch.Value = 'Log';
            app.yAbsSwitch = uiswitch(app.UIFigure, 'slider');
            app.yAbsSwitch.Items = {'Absolut', 'Relatiu'};
            app.yAbsSwitch.ValueChangedFcn = createCallbackFcn(app, @switchCanviat, true);
            app.yAbsSwitch.Value = 'Absolut';
            app.EscalaDropDownLabel = uilabel(app.UIFigure);
            app.EscalaDropDownLabel.Text = 'Escala';
            app.EscalaDropDown = uidropdown(app.UIFigure);
            app.EscalaDropDown.Items = {'400 mV', 'Totes', 'Option 3', 'Option 4'};
            app.EscalaDropDown.Value = '400 mV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_codi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end